classdef lsAudioTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AboutMenu                matlab.ui.container.Menu
        OpenfileMenu             matlab.ui.container.Menu
        SavefileMenu             matlab.ui.container.Menu
        RTlabel                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        BufferLengthSlider       matlab.ui.control.Slider
        BufferLengthSliderLabel  matlab.ui.control.Label
        Reverb                   matlab.ui.container.Panel
        MixKnob                  matlab.ui.control.Knob
        MixKnobLabel             matlab.ui.control.Label
        ReverbTimeKnob           matlab.ui.control.Knob
        ReverbTimeKnobLabel      matlab.ui.control.Label
        ReverbLabel              matlab.ui.control.Label
        Equalizer                matlab.ui.container.Panel
        HPFRoda                  matlab.ui.control.Knob
        HPFGaindBKnob_2Label     matlab.ui.control.Label
        BPFRoda                  matlab.ui.control.Knob
        BPFGaindBKnob_2Label     matlab.ui.control.Label
        LPFRoda                  matlab.ui.control.Knob
        LPFGaindBKnob_2Label     matlab.ui.control.Label
        EqualizerLabel           matlab.ui.control.Label
        Control                  matlab.ui.container.Panel
        on_off                   matlab.ui.control.Switch
        original_filter          matlab.ui.control.Switch
        Freq_Time                matlab.ui.control.Switch
        ActiveEffectsLabel       matlab.ui.control.Label
        EffectDropDown           matlab.ui.control.DropDown
        UI2Axes                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        SOS_LP_, SOS_BP_, SOS_HP_ % Matius de coeficients de les seccions d'ordre 2 corresponents al disseny les 3 subbandes de l equalitzador (disseny original).
        G_LP_, G_BP_, G_HP_ % Vectors de guanys de les seccions d'ordre 2 corresponents al disseny les 3 subbandes de l equalitzador (disseny original).
        G_LPF, G_BPF, G_HPF % Valor dels guanys lineals que apliquem a les 3 subbandes de l equalitzador (s’hauran de convertir de dB a valors lineals).
        fs % freqüència de mostratge a la que han de treballar els conversors A/D i D/A (valor 44100 Hz).
        H_LP, H_BP, H_HP %Funcions de transferencia
        H_EQ %Funcio de transferencia del equalitzador

        deviceReader %objecte que extreu les trames d'àudio del conversor A/D.
        deviceWriter % objecte que envia la trama d'àudio amb efecte cap al conversorD/A.
        audio % vector que conté una trama d'àudio capturada pel conversor A/D.
        audioEfect % vector que conté una trama d'àudio processada amb l efecte corresponent per a enviar al conversor D/A.
        audiof % vector que conté l'àudio d'entrada o amb efecte en el domini de la freqüència (per a fer la seva visualització en la segona figura de la interfície)
        heq_length % longitud en mostres de la resposta impulsional de l'equalitzador.
        zieq % registre de condicions inicials/finals del filtre equalitzador.
        zirev % registre de condicions inicials/finals del filtre reverberador.
        h_reverb % resposta impulsional del filtre reverberador.

        H_AUX
    end
    
    methods (Access = private)
         % Function to update filter gains and reverb impulse response
        function updateFilters(app)
            app.G_LPF = app.LPFRoda.Value;
            app.G_BPF = app.BPFRoda.Value;
            app.G_HPF = app.HPFRoda.Value;


            T_reverb = app.ReverbTimeKnob.Value;    
            a_reverb = 10^(-6/T_reverb); %s'extreu de la formula
            Lh = round(T_reverb*app.fs);
            app.h_reverb = (a_reverb.^((0:(Lh-1))/app.fs)).*randn(1,Lh);
            app.h_reverb = app.h_reverb/norm(app.h_reverb);

            %Apliquem el codi fet a la F1
            N = 500;
            x = [1;zeros(N-1,1)];
            
            SOS_L = app.SOS_LP_;
            SOS_B = app.SOS_BP_;
            SOS_H = app.SOS_HP_;

            SOS_L(1,1:3) = app.SOS_LP_(1,1:3)*db2mag(app.G_LPF)*prod(app.G_LP_);
            SOS_B(1,1:3) = app.SOS_BP_(1,1:3)*db2mag(app.G_BPF)*prod(app.G_BP_);
            SOS_H(1,1:3) = app.SOS_HP_(1,1:3)*db2mag(app.G_HPF)*prod(app.G_HP_);

            app.H_LP = sosfilt(SOS_L,x);
            app.H_BP = sosfilt(SOS_B,x);
            app.H_HP = sosfilt(SOS_H,x);
    
            app.H_EQ = app.H_LP + app.H_BP + app.H_HP;
             
            app.H_AUX = app.H_EQ;
            heq = app.H_EQ(1:30);    
            app.H_EQ = heq;
            app.heq_length = length(app.H_EQ);
            
        end
        
        % Helper function to update a single filter response
        function updateFilterResponse(app, filterType, SOS, gain)
          gainFactor = db2mag(gain) * prod(app.(strcat(filterType, 'G_')));
          SOS(1, 1:3) = SOS(1, 1:3) * gainFactor;
          app.(strcat('H_', filterType)) = sosfilt(SOS, [1; zeros(499, 1)]);
        end
        

        % Funció que s'encarrega de visualitzar en temps o en freqüència el
        % senyal filtrat o original per cada trama d'àudio.
        function freq_temp(app)
            switch(app.Freq_Time.Value)
                case "Freq"
                    [app.audiof, f] = freqz(app.audioEfect, 1, length(app.audioEfect), app.fs);
                    app.audiof = app.audiof * 0.7 + 0.3 * abs(app.audiof(1:length(app.audiof))).^2;
                    plot(app.UI2Axes, f, 20 * log10(abs(app.audiof)));
                    title(app.UI2Axes, 'Input signal spectrum', 'Color', 'white');
                    xlabel(app.UI2Axes, "Frequency (Hz)");
                    ylabel(app.UI2Axes, "Amplitude (dB)");
                    ylim(app.UI2Axes, 'padded');
                    xlim(app.UIAxes, 'tight');
                    
                case "Time"
                    cla(app.UI2Axes);
                    app.UI2Axes.XScale = 'linear';
                    t = (0:length(app.audioEfect) - 1) / app.fs;
                    plot(app.UI2Axes, t, app.audioEfect);
                    title(app.UI2Axes, 'Time signal', 'Color', 'white');
                    xlabel(app.UI2Axes, "Time (s)");
                    ylabel(app.UI2Axes, "Amplitude");
                    xlim(app.UIAxes, 'tight');
                    ylim(app.UI2Axes, 'padded');
            end
        end

        function origi_filt(app)            
            switch(app.original_filter.Value)
                case "Original"
                    app.audioEfect = app.audio;
                case "Filter"
                    switch(app.EffectDropDown.Value)
                        case "Equalizer"
                            [app.audioEfect, app.zieq] = filter(app.H_EQ,1, app.audio, app.zieq);
                            
                        case "Reverb"
                            audioIN = app.audio;
                            [app.audioEfect, app.zirev] = filter(app.h_reverb,1, audioIN, app.zirev);
                            app.audioEfect = audioIN * (1 - app.MixKnob.Value/100) +  app.audioEfect*(app.MixKnob.Value/100);

                    end    
            end
        end    
        
        % Funció que bloqueja o desbloqueja en funció del valor del boolean
        % flag a tots els botons i interruptors que permeten modificar el
        % filtre o gràfiques. Hi ha un atribut, per cada knob/switch, que
        % fa d'enable de cadascun dels controls de la interfície, l'atribut
        % és el següent, per exemple, knob.enable = true.
        function blockbuttons(app,flag)
            % Bloqueja/Desbloqueja els switches menys el on/off
            app.Freq_Time.Enable = flag;
            app.original_filter.Enable = flag;
            % Bloqueja/Desbloqueja els knobs de equalizer i reverb
            app.ReverbTimeKnob.Enable = flag;
            app.MixKnob.Enable = flag;
            app.LPFRoda.Enable = flag;
            app.BPFRoda.Enable = flag;
            app.HPFRoda.Enable = flag;
            app.BufferLengthSlider.Enable = flag;
            app.EffectDropDown.Enable = flag;
        end

        % Funció que processa cada trama d'àudio que llegeix el fileReader
        % en funció del efecte seleccionat. Aquesta funció serà molt
        % semblant a la ja implementada a la Fase 1, però amb algunes
        % diferències, degut al fet que estem processant una trama d'un
        % flux continu d'àudio, i cal garantir la continuïtat del procés de
        % filtratge digital.
        function processAudio(app)
            % Actualitza les variables globals
            updateFilters(app);
            % En funció de l'efecte a aplicar, es realitzarà un filtrat o
            % un altre
            origi_filt(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Carrego els filtres
            load('filtersSOS.mat', 'G_BP', 'G_HP', 'G_LP', 'SOS_BP', 'SOS_HP', 'SOS_LP');
            
            %Assigno la mtriu SOS
            app.SOS_LP_ = SOS_LP;
            app.SOS_BP_ = SOS_BP;
            app.SOS_HP_ = SOS_HP;

            %Assigno el guany de cada filtre
            app.G_LP_ = G_LP;
            app.G_BP_ = G_BP;
            app.G_HP_ = G_HP;
            app.fs = 44100;
       
            %Agafo el valor de la GUI
            app.G_LPF = app.LPFRoda.Value;
            app.G_BPF = app.BPFRoda.Value;
            app.G_HPF = app.HPFRoda.Value;
            
            %Carrego la imatge guardada
            imageFile = 'LSAudioTools.png';
            img = imread(imageFile);
            app.Image.ImageSource = img;

            updateFilters(app);
            grafica(app);

        end

        % Value changed function: on_off
        function on_offValueChanged(app, event)
            if app.on_off.Value == "ON"
                 % Inicialitzem els buffers de la reverb i l'equalitzador
                 % per bandes
                 app.zirev = zeros(1,length(app.h_reverb)-1);
                 app.zieq = zeros(1,app.heq_length-1);
                 % Inicialitzem la comunicació amb els conversors AD i DA, i
                 % fixem la freqüència de mostratge a 44100 Hz
                 app.deviceReader = audioDeviceReader(app.fs, round(app.BufferLengthSlider.Value));
                 app.deviceWriter = audioDeviceWriter('SampleRate',round(app.deviceReader.SampleRate));
                 % Bloquejem tots els altres controls de la interfície menys
                 % l'on_off
                 app.blockbuttons(false);
                 % Inicializem comptador de temps
                 tic
                 try
                     while app.on_off.Value == "ON"
                         app.RTlabel.Text = ('Hola, estoy dentro');
                         app.audio = app.deviceReader();
                         processAudio(app);
                         app.deviceWriter(app.audioEfect)
                         freq_temp(app);
                         totalTime1 = toc;
                         tic
                         pause(0.01);
                         if (totalTime1 < (app.BufferLengthSlider.Value/app.fs))
                             app.RTlabel.Text = ('Hola, if');
                         else
                             app.RTlabel.Text = ('Hola, else');
                         end
                     end
                 catch
                    app.RTlabel.Text = ('Real-time effect stopped');
                    tic
                 end
                     % Desbloquejem tots els controls de la interfície
                     app.blockbuttons(true);
                     % Finalitzem la comunicació amb els conversors AD i DA
                     release(app.deviceReader);
                     release(app.deviceWriter);
            end
        end

        % Value changed function: BPFRoda, EffectDropDown, HPFRoda, 
        % ...and 3 other components
        function grafica(app, event)
            updateFilters(app)
          
            switch(app.EffectDropDown.Value)
                    case "Equalizer"
                        n = 2048;
                        [H_L,f_l] = freqz(app.H_LP,1,n,app.fs);
                        [H_B,f_b] = freqz(app.H_BP,1,n,app.fs);
                        [H_H,f_h] = freqz(app.H_HP,1,n,app.fs);
                        [H,f] = freqz(app.H_AUX,1,n,app.fs);
                        
                        semilogx(app.UIAxes, f_l, 20*log10(abs(H_L)));
                        title(app.UIAxes, 'EQUALIZER Response', 'Color', 'white');
                        xlabel(app.UIAxes, "Frequency (Hz)");
                        ylabel(app.UIAxes, "Magnitude (dB)");
                        hold(app.UIAxes, 'on');
                        semilogx(app.UIAxes, f_b, 20*log10(abs(H_B)));
                        semilogx(app.UIAxes, f_h, 20*log10(abs(H_H)));
                        semilogx(app.UIAxes, f, 20*log10(abs(H)));
                        % Crear la layenda
                        hLegend = legend(app.UIAxes, 'Lowpass filter', 'Bandpass filter', 'Highpass filter', 'Equalizer', 'Location', 'northeast');
                        % Desactivar la interactividad de la leyenda
                        hLegend.Interpreter = 'none';
                        hLegend.PickableParts = 'none';
                        hold(app.UIAxes, 'off');
                        ylim (app.UIAxes, [-40,30]);
                        xlim (app.UIAxes,'tight');
                        grid(app.UIAxes, 'on');                        
                        app.RTlabel.Text = ('Alba pedazo de prostituta, reina');
            
                    case "Reverb"
                        cla(app.UIAxes);
                        app.UIAxes.XScale = 'linear';
                        % Ocultar la leyenda en el caso "Reverb"
                        legend(app.UIAxes, 'off');
                        t_audioRev = (0:length(app.h_reverb)-1) / app.fs;
                        plot(app.UIAxes, t_audioRev, app.h_reverb);
                        title(app.UIAxes, 'Reverb impulse response', 'Color', 'white');
                        xlabel(app.UIAxes, "time");
                        ylabel(app.UIAxes, "amplitude");
                        xlim(app.UIAxes, 'tight');
                        ylim (app.UIAxes, [-0.07,0.07]);   
                        grid(app.UIAxes, 'on');
            end  
        end

        % Button down function: UI2Axes, UIAxes
        function UIAxesButtonDown(app, event)
            %Tanco les modificacions per les grafiques
            pan(app.UIAxes, 'off');
            zoom(app.UIAxes, 'off');
            pan(app.UI2Axes, 'off');
            zoom(app.UI2Axes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = 'AboutMenu';
            app.OpenfileMenu = uimenu(app.AboutMenu);
            app.OpenfileMenu.Text = 'Open';
            app.SavefileMenu = uimenu(app.AboutMenu);
            app.SavefileMenu.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UI2Axes = uiaxes(app.UIFigure);
            xlabel(app.UI2Axes, 'Frequency (Hz)')
            ylabel(app.UI2Axes, 'Amplitude (dB)')
            app.UI2Axes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Control = uipanel(app.UIFigure);
            app.EffectDropDown = uidropdown(app.Control);
            app.EffectDropDown.Items = {'Equalizer', 'Reverb'};
            app.EffectDropDown.ValueChangedFcn = createCallbackFcn(app, @grafica, true);
            app.EffectDropDown.Value = 'Equalizer';
            app.ActiveEffectsLabel = uilabel(app.Control);
            app.ActiveEffectsLabel.Text = 'Active Effects';
            app.Freq_Time = uiswitch(app.Control, 'slider');
            app.Freq_Time.Items = {'Freq', 'Time'};
            app.Freq_Time.Value = 'Freq';
            app.original_filter = uiswitch(app.Control, 'slider');
            app.original_filter.Items = {'Original', 'Filter'};
            app.original_filter.Value = 'Original';
            app.on_off = uiswitch(app.Control, 'slider');
            app.on_off.Items = {'ON', 'OFF'};
            app.on_off.ValueChangedFcn = createCallbackFcn(app, @on_offValueChanged, true);
            app.on_off.Value = 'OFF';
            app.Equalizer = uipanel(app.UIFigure);
            app.EqualizerLabel = uilabel(app.Equalizer);
            app.EqualizerLabel.Text = 'Equalizer';
            app.LPFGaindBKnob_2Label = uilabel(app.Equalizer);
            app.LPFGaindBKnob_2Label.Text = 'LPF Gain (dB)';
            app.LPFRoda = uiknob(app.Equalizer, 'continuous');
            app.LPFRoda.Limits = [-30 20];
            app.LPFRoda.MajorTicks = [-30 -20 -10 0 10 20];
            app.LPFRoda.ValueChangedFcn = createCallbackFcn(app, @grafica, true);
            app.LPFRoda.Value = -15;
            app.BPFGaindBKnob_2Label = uilabel(app.Equalizer);
            app.BPFGaindBKnob_2Label.Text = 'BPF Gain (dB)';
            app.BPFRoda = uiknob(app.Equalizer, 'continuous');
            app.BPFRoda.Limits = [-30 20];
            app.BPFRoda.MajorTicks = [-30 -20 -10 0 10 20];
            app.BPFRoda.ValueChangedFcn = createCallbackFcn(app, @grafica, true);
            app.BPFRoda.Value = -15;
            app.HPFGaindBKnob_2Label = uilabel(app.Equalizer);
            app.HPFGaindBKnob_2Label.Text = 'HPF Gain (dB)';
            app.HPFRoda = uiknob(app.Equalizer, 'continuous');
            app.HPFRoda.Limits = [-30 20];
            app.HPFRoda.MajorTicks = [-30 -20 -10 0 10 20];
            app.HPFRoda.ValueChangedFcn = createCallbackFcn(app, @grafica, true);
            app.HPFRoda.Value = -15;
            app.Reverb = uipanel(app.UIFigure);
            app.ReverbLabel = uilabel(app.Reverb);
            app.ReverbLabel.Text = 'Reverb';
            app.ReverbTimeKnobLabel = uilabel(app.Reverb);
            app.ReverbTimeKnobLabel.Text = 'Reverb.Time';
            app.ReverbTimeKnob = uiknob(app.Reverb, 'continuous');
            app.ReverbTimeKnob.Limits = [0.5 5];
            app.ReverbTimeKnob.MajorTicks = [0.5 1.5 2.5 3.5 4.5 5];
            app.ReverbTimeKnob.ValueChangedFcn = createCallbackFcn(app, @grafica, true);
            app.ReverbTimeKnob.Value = 1;
            app.MixKnobLabel = uilabel(app.Reverb);
            app.MixKnobLabel.Text = '% Mix';
            app.MixKnob = uiknob(app.Reverb, 'continuous');
            app.MixKnob.MajorTicks = [0 20 40 60 80 100];
            app.MixKnob.ValueChangedFcn = createCallbackFcn(app, @grafica, true);
            app.MixKnob.Value = 50;
            app.BufferLengthSliderLabel = uilabel(app.UIFigure);
            app.BufferLengthSliderLabel.Text = 'Buffer Length';
            app.BufferLengthSlider = uislider(app.UIFigure);
            app.BufferLengthSlider.Limits = [1024 10240];
            app.BufferLengthSlider.MajorTicks = [];
            app.BufferLengthSlider.MinorTicks = [1024 1168 1312 1456 1600 1744 1888 2032 2176 2320 2464 2608 2752 2896 3040 3184 3328 3472 3616 3760 3904 4048 4192 4336 4480 4624 4768 4912 5056 5200 5344 5488 5632 5776 5920 6064 6208 6352 6496 6640 6784 6928 7072 7216 7360 7504 7648 7792 7936 8080 8224 8368 8512 8656 8800 8944 9088 9232 9376 9520 9664 9808 9952 10096 10240];
            app.BufferLengthSlider.Value = 1024;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LSAudioTools.png');
            app.RTlabel = uilabel(app.UIFigure);
            app.RTlabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsAudioTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end